classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        CurrentprojectLabel       matlab.ui.control.Label
        LoadProjectButton         matlab.ui.control.Button
        SaveProjectButton         matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        RAWTab                    matlab.ui.container.Tab
        ArrayNamePreview          matlab.ui.control.Label
        ZeroexpandButton          matlab.ui.control.Button
        ZsizeEditField            matlab.ui.control.NumericEditField
        ZsizeEditFieldLabel       matlab.ui.control.Label
        YsizeEditField            matlab.ui.control.NumericEditField
        YsizeEditFieldLabel       matlab.ui.control.Label
        XsizeEditField            matlab.ui.control.NumericEditField
        XsizeEditFieldLabel       matlab.ui.control.Label
        outputnameEditField       matlab.ui.control.EditField
        outputnameEditFieldLabel  matlab.ui.control.Label
        FFTButton                 matlab.ui.control.Button
        OpenFileRawButton         matlab.ui.control.Button
        FilesRawListBox           matlab.ui.control.ListBox
        FilesListBoxLabel         matlab.ui.control.Label
        ARRAYSTab                 matlab.ui.container.Tab
        SaveFileArrayButton       matlab.ui.control.Button
        newnameEditField          matlab.ui.control.EditField
        newnameEditFieldLabel     matlab.ui.control.Label
        SavetolistButton          matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        x2Button                  matlab.ui.control.Button
        SubsamplingListBox        matlab.ui.control.ListBox
        SubsamplingListBoxLabel   matlab.ui.control.Label
        OpenFileArrayButton       matlab.ui.control.Button
        FilesListBox_Array        matlab.ui.control.ListBox
        FilesListBox_2Label       matlab.ui.control.Label
        MASKSTab                  matlab.ui.container.Tab
        WARNIGNSavemaskwithdafaultnameBWLabel  matlab.ui.control.Label
        MaskNameEditField         matlab.ui.control.EditField
        MaskNameEditFieldLabel    matlab.ui.control.Label
        LayerEditField            matlab.ui.control.NumericEditField
        LayerEditFieldLabel       matlab.ui.control.Label
        ArrayDropDown             matlab.ui.control.DropDown
        ArrayDropDownLabel        matlab.ui.control.Label
        CreateButton              matlab.ui.control.Button
        SaveMaskFileButton        matlab.ui.control.Button
        OpenFileMaskButton        matlab.ui.control.Button
        MaskListBox               matlab.ui.control.ListBox
        FilesListBox_3Label       matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        RightPanel                matlab.ui.container.Panel
        MaskLabel                 matlab.ui.control.Label
        ArrayLabel_2              matlab.ui.control.Label
        MaskLabel_2               matlab.ui.control.Label
        ArraysLabel               matlab.ui.control.Label
        ArrayLabel                matlab.ui.control.Label
        View3DButton              matlab.ui.control.Button
        Tree2                     matlab.ui.container.Tree
        QualityNode               matlab.ui.container.TreeNode
        CNRNode                   matlab.ui.container.TreeNode
        SNRNode                   matlab.ui.container.TreeNode
        CommonNode                matlab.ui.container.TreeNode
        MEANNode                  matlab.ui.container.TreeNode
        SUBNode                   matlab.ui.container.TreeNode
        CalculateButton           matlab.ui.control.Button
        CompareButton             matlab.ui.control.Button
        ShowButton                matlab.ui.control.Button
        AnalyseLabel              matlab.ui.control.Label
        RawContextMenu            matlab.ui.container.ContextMenu
        RawDeleteMenu             matlab.ui.container.Menu
        RawRenameMenu             matlab.ui.container.Menu
        ArraysContextMenu         matlab.ui.container.ContextMenu
        ArrayDeleteMenu           matlab.ui.container.Menu
        ArrayRenameMenu           matlab.ui.container.Menu
        MaskContextMenu           matlab.ui.container.ContextMenu
        MaskDeleteMenu            matlab.ui.container.Menu
        MaskRenameMenu            matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        somePath % Путь к файлу или папке
        serFilePath
    end
    
    properties (Access = public)
        RawFiles = {} % Description
        FFTArrays = {} % Новое свойство для хранения структур FFT-массивов
        MaskList = {}
    end
    
    methods (Access = public)

        function updateLists(app)
            %% Обновляем список RawFiles
            rawList = {};
            rawListData = {};
            for i = 1:length(app.RawFiles)
                fileInfo = app.RawFiles{i};
                % Формируем «красивое» имя для отображения
                displayName = sprintf('%s [%dx%dx%d]', fileInfo.listName, ...
                    fileInfo.dimensions(1), fileInfo.dimensions(2), fileInfo.dimensions(3));

                rawList{end+1} = displayName;
                % А здесь сохраняем реальное имя переменной,
                % которое лежит в базовом workspace
                rawListData{end+1} = i;
            end

            % Присваиваем списку
            app.FilesRawListBox.Items = rawList;
            app.FilesRawListBox.ItemsData = rawListData;

            %% Аналогично для FFTArrays
            fftList = {};
            fftListData = {};
            for i = 1:length(app.FFTArrays)
                fileInfo = app.FFTArrays{i};
                dims = fileInfo.dimensions;
                if numel(dims) < 3
                    dims(end+1:3) = 1;
                end
                displayName = sprintf('%s [%dx%dx%d]', ...
                    fileInfo.listName, dims(1), dims(2), dims(3));

                fftList{end+1} = displayName;
                fftListData{end+1} = i;
            end

            app.FilesListBox_Array.Items = fftList;
            app.FilesListBox_Array.ItemsData = fftListData;

            maskList = {};
            maskListData = {};
            for i = 1:length(app.MaskList)
                maskInfo = app.MaskList{i};
                dims = maskInfo.dimensions;
                if numel(dims) < 2
                    dims(end+1:2) = 1;
                end
                displayName = sprintf('%s [%dx%d]', maskInfo.listName, dims(1), dims(2));
                maskList{end+1} = displayName;
                maskListData{end+1} = i;
            end

            app.MaskListBox.Items = maskList;
            app.MaskListBox.ItemsData = maskListData;
        end
    end
    
    methods (Access = private)

        % Вспомогательный метод для парсинга строки вида "X=10" -> ('X', 10)
        function [dimLabel, dimValue] = parseSubsamplingItem(app, itemStr)
            parts = split(itemStr, '=');
            dimLabel = parts{1};
            dimValue = str2double(parts{2});
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function FormStartUp(app)
            %x53 raw default load FOR_DEBUG!
            rawFileStruct = load('x56.mat');
            app.RawFiles{end+1} = rawFileStruct.RawFiles{1};
            app.updateLists();
        end

        % Button pushed function: OpenFileRawButton
        function OpenRaw(app, event)
            % Открываем диалог выбора файла для файла ser
            [file, path] = uigetfile({'*.*','Все файлы'}, 'Выберите файл ser');
    
            % Если пользователь не выбрал файл, прекращаем выполнение
            if isequal(file,0)
               uialert(app.UIFigure, 'Файл не выбран.', 'Предупреждение');
               return;
            end
    
            % Формируем полный путь к файлу
            serFilePath = fullfile(path, file);

            % Вызываем функцию для чтения файла ser и сопутствующих файлов с размерностями
            openRawFileCallback(app,serFilePath); 
        end

        % Callback function
        function AvaliableRawFilesListBox(app, event)
            
            
        end

        % Button pushed function: FFTButton
        function FFT(app, event)
            FFTButtonPushed(app)
        end

        % Value changed function: FilesRawListBox, outputnameEditField
        function RawListItemChanged(app, event)
            indexRawFile = app.FilesRawListBox.Value;
            if isempty(indexRawFile)
                app.ArrayNamePreview.Text = "raw file is not chosen!";
            else
                app.ArrayNamePreview.Text = strcat(app.outputnameEditField.Value, app.RawFiles{indexRawFile}.listName);
            end
        end

        % Value changed function: FilesListBox_Array
        function ArrayListValueChanged(app, event)

        indexFFTFile = app.FilesListBox_Array.Value;
        if isempty(indexFFTFile)
            return;
        end

        % Извлекаем структуру из app.FFTArrays
        fftInfo = app.FFTArrays{indexFFTFile};
        dims = fftInfo.dimensions;

        % Гарантируем, что размерность будет [X Y Z]
        if numel(dims) < 3
            dims(end+1:3) = 1; 
        end

        % Заполняем SubsamplingListBox форматированными строками
        app.SubsamplingListBox.Items = { ...
            sprintf('X=%d', dims(1)), ...
            sprintf('Y=%d', dims(2)), ...
            sprintf('Z=%d', dims(3)) ...
        };

        % Автоматически копируем имя массива в newnameEditField
        app.newnameEditField.Value = fftInfo.listName;
        end

        % Button pushed function: x2Button
        function ButtonX2Clicked(app, event)
            selectedItem = app.SubsamplingListBox.Value;
            if isempty(selectedItem)
                return;
            end

            [dimLabel, dimValue] = app.parseSubsamplingItem(selectedItem);
            dimValue = dimValue * 2;  % умножение на 2
            newItem = sprintf('%s=%d', dimLabel, dimValue);

            % Находим индекс элемента в Items и заменяем
            idx = find(strcmp(app.SubsamplingListBox.Items, selectedItem));
            app.SubsamplingListBox.Items{idx} = newItem;
            app.SubsamplingListBox.Value = newItem;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            selectedItem = app.SubsamplingListBox.Value;
            if isempty(selectedItem)
                return;
            end

            [dimLabel, dimValue] = app.parseSubsamplingItem(selectedItem);

            % Проверяем, делится ли значение нацело
            if mod(dimValue, 2) ~= 0
                uialert(app.UIFigure, ...
                    'Значение не делится на 2 без остатка!', ...
                    'Ошибка');
                return;
            end

            dimValue = dimValue / 2;  % делим на 2
            newItem = sprintf('%s=%d', dimLabel, dimValue);

            idx = find(strcmp(app.SubsamplingListBox.Items, selectedItem));
            app.SubsamplingListBox.Items{idx} = newItem;
            app.SubsamplingListBox.Value = newItem;
        end

        % Button pushed function: SavetolistButton
        function SavetolistButtonPushed(app, event)
            indexFFTFile = app.FilesListBox_Array.Value;
            if isempty(indexFFTFile)
                uialert(app.UIFigure, 'Не выбран элемент массива!', 'Ошибка');
                return;
            end

            % Извлекаем исходную структуру
            oldFFTInfo = app.FFTArrays{indexFFTFile};

            % Собираем новые размеры из SubsamplingListBox
            newDims = zeros(1,3);
            for i = 1:3
                itemStr = app.SubsamplingListBox.Items{i};
                [~, dimValue] = app.parseSubsamplingItem(itemStr);
                newDims(i) = dimValue;
            end

            % Создаём новый объект структуры (копируя старую)
            newFFTInfo = oldFFTInfo;
            newFFTInfo.dimensions = newDims;

            % Берём имя из newnameEditField
            newName = app.newnameEditField.Value;
            newFFTInfo.listName = newName;

            % Добавляем новый элемент в конец app.FFTArrays
            app.FFTArrays{end+1} = newFFTInfo;

            % При желании — сохранить в base workspace (или в файл):
            assignin('base', newName, newFFTInfo);

            % Обновляем списки, чтобы новый элемент отобразился
            app.updateLists();

            uialert(app.UIFigure, ...
                sprintf('Создан новый массив "%s" с размерами [%dx%dx%d].', ...
                newName, newDims(1), newDims(2), newDims(3)), ...
                'Успех');
        end

        % Button down function: MASKSTab
        function MASKSTabButtonDown(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            % Если выбрана вкладка MASKSTab
            if selectedTab == app.MASKSTab
                fftItems = {};
                for i = 1:length(app.FFTArrays)
                    fileInfo = app.FFTArrays{i};
                    dims = fileInfo.dimensions;
                    if numel(dims) < 3
                        dims(end+1:3) = 1;
                    end
                    fftItems{end+1} = sprintf('%s [%dx%dx%d]', fileInfo.listName, dims(1), dims(2), dims(3));
                end
                app.ArrayDropDown.Items = fftItems;
                if ~isempty(fftItems)
                    app.ArrayDropDown.Value = fftItems{1};
                end
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            layerNumber = app.LayerEditField.Value;
            selectedItem = app.ArrayDropDown.Value;
            if isempty(selectedItem)
                uialert(app.UIFigure, 'Не выбран массив в ArrayDropDown.', 'Ошибка');
                return;
            end

            % Предполагаем, что в строке ArrayDropDown первым словом является имя массива
            tokens = split(selectedItem, ' ');
            selectedName = tokens{1};

            % Поиск структуры с таким именем в FFTArrays
            found = false;
            fftStruct = [];
            for i = 1:length(app.FFTArrays)
                if strcmp(app.FFTArrays{i}.listName, selectedName)
                    fftStruct = app.FFTArrays{i};
                    found = true;
                    break;
                end
            end
            if ~found
                uialert(app.UIFigure, 'Выбранный массив не найден в FFTArrays.', 'Ошибка');
                return;
            end

            dims = fftStruct.dimensions;
            if numel(dims) < 3
                dims(end+1:3) = 1;
            end

            % Проверяем, что введённый номер слоя находится в пределах [1, dims(3)]
            if layerNumber < 1 || layerNumber > dims(3)
                uialert(app.UIFigure, sprintf('Номер слоя должен быть от 1 до %d.', dims(3)), 'Ошибка');
                return;
            end

            % Предполагается, что в fftStruct содержится поле data с 3D массивом
            if ~isfield(fftStruct, 'array')
                uialert(app.UIFigure, 'В выбранном массиве отсутствуют данные (поле "data").', 'Ошибка');
                return;
            end

            % Извлекаем 2D срез по оси Z
            slice2D = fftStruct.array(:,:,layerNumber);
            % Преобразование slice2D перед вызовом imageSegmenter:
            % Способ 1: Использовать только реальную часть
            % slice2D = real(slice2D);

            % Способ 2: Использовать только мнимую часть
            % slice2D = imag(slice2D);

            % Способ 3: Использовать модуль (абсолютное значение)
            slice2D = abs(slice2D);
            % Вызываем imageSegmenter с выбранным срезом
            imageSegmenter(slice2D);
            % После завершения работы imageSegmenter начинаем ожидание переменной BW (логический 2D массив)
            disp('Ожидание переменной BW...');
            while evalin('base', 'exist(''BW'', ''var'')') == 0
                pause(0.1);
            end
            BW = evalin('base', 'BW');

            % Проверка: BW должен быть логическим 2D массивом
            if ~islogical(BW) || ~ismatrix(BW)
                uialert(app.UIFigure, 'Переменная BW должна быть логическим 2D массивом.', 'Ошибка');
                return;
            end

            % Создаём описание маски (аналогично описанию в RawFiles/FFTArrays)
            maskInfo.listName = app.MaskNameEditField.Value;  % Имя берём из поля MaskNameEditField
            maskInfo.dimensions = size(BW);
            maskInfo.BW = BW;  % можно сохранить саму маску, если потребуется

            % Удаляем переменную BW из base workspace
            evalin('base', 'clear BW');

            % Добавляем описание маски в конец app.MaskList
            app.MaskList{end+1} = maskInfo;

            % Обновляем списки, чтобы новый элемент отобразился (FilesListBox_3)
            app.updateLists();

            uialert(app.UIFigure, sprintf('Маска "%s" добавлена.', maskInfo.listName), 'Успех');
        end

        % Button pushed function: SaveFileArrayButton
        function SaveFileArrayButtonPushed(app, event)
            % Выбираем элемент из FilesListBox_Array (индекс элемента FFTArrays)
            indexFFTFile = app.FilesListBox_Array.Value;
            if isempty(indexFFTFile)
                uialert(app.UIFigure, 'Не выбран элемент массива в FFTArrays.', 'Ошибка');
                return;
            end
            fftStruct = app.FFTArrays{indexFFTFile};

            % Запрашиваем имя файла для сохранения
            [file, path] = uiputfile({'*.bin','Binary Files'}, 'Сохранить Array');
            if isequal(file,0)
                return;
            end
            filename = fullfile(path, file);

            fid = fopen(filename, 'w');
            if fid == -1
                uialert(app.UIFigure, 'Ошибка открытия файла для записи.', 'Ошибка');
                return;
            end

            % Записываем бинарно поле array (предполагается тип double)
            fwrite(fid, fftStruct.array, 'double');

            % Записываем маркер для разделения бинарных данных и метаинформации
            marker = 'META_START';
            fwrite(fid, marker, 'char');

            % Записываем метаинформацию: сначала имя, затем размеры (разделённые запятыми)
            metaStr = sprintf('\n%s\n', fftStruct.listName);
            fwrite(fid, metaStr, 'char');
            dimsStr = sprintf('%d,', fftStruct.dimensions);
            dimsStr = dimsStr(1:end-1);  % удаляем завершающую запятую
            dimsStr = sprintf('%s\n', dimsStr);
            fwrite(fid, dimsStr, 'char');

            fclose(fid);
            uialert(app.UIFigure, 'Array сохранён.', 'Успех');
        end

        % Button pushed function: OpenFileArrayButton
        function OpenFileArrayButtonPushed(app, event)
            [file, path] = uigetfile({'*.bin','Binary Files'}, 'Открыть Array');
            if isequal(file,0)
                return;
            end
            filename = fullfile(path, file);

            fid = fopen(filename, 'r');
            if fid == -1
                uialert(app.UIFigure, 'Ошибка открытия файла для чтения.', 'Ошибка');
                return;
            end

            % Читаем весь файл как последовательность uint8 (байтов)
            fileBytes = fread(fid, inf, 'uint8=>uint8');
            fclose(fid);

            % Преобразуем байты в строку для поиска маркера
            fileStr = char(fileBytes');
            marker = 'META_START';
            markerIdx = strfind(fileStr, marker);
            if isempty(markerIdx)
                uialert(app.UIFigure, 'Маркер META_START не найден в файле.', 'Ошибка');
                return;
            end

            % Бинарная часть – от начала файла до начала маркера
            binaryBytes = fileBytes(1:markerIdx-1);
            % Количество записанных чисел (double: 8 байт)
            if mod(numel(binaryBytes), 8) ~= 0
                uialert(app.UIFigure, 'Размер бинарных данных не кратен 8.', 'Ошибка');
                return;
            end
            arrayData = typecast(binaryBytes, 'double');

            % Метаинформация – часть после маркера
            metaData = fileStr(markerIdx+length(marker):end);
            metaLines = strsplit(metaData, '\n');
            % Удаляем пустые строки
            metaLines = metaLines(~cellfun('isempty', metaLines));
            if numel(metaLines) < 2
                uialert(app.UIFigure, 'Недостаточно мета данных.', 'Ошибка');
                return;
            end
            listName = metaLines{1};
            dimsStr = metaLines{2};
            dimsCell = strsplit(dimsStr, ',');
            dims = cellfun(@str2double, dimsCell);

            % Приводим arrayData к нужной размерности
            arrayData = reshape(arrayData, dims);

            % Создаём структуру FFTArrays и заполняем поле filePath
            newFFTStruct.array = arrayData;
            newFFTStruct.listName = listName;
            newFFTStruct.dimensions = dims;
            newFFTStruct.filePath = filename;

            % Добавляем структуру в конец списка FFTArrays и обновляем списки
            app.FFTArrays{end+1} = newFFTStruct;
            app.updateLists();

            uialert(app.UIFigure, 'Array открыт и добавлен в список.', 'Успех');
        end

        % Button pushed function: SaveMaskFileButton
        function SaveMaskFileButtonPushed(app, event)
            indexMask = app.MaskListBox.Value;
            if isempty(indexMask)
                uialert(app.UIFigure, 'Не выбран элемент маски.', 'Ошибка');
                return;
            end
            maskStruct = app.MaskList{indexMask};

            [file, path] = uiputfile({'*.bin','Binary Files'}, 'Сохранить Mask');
            if isequal(file,0)
                return;
            end
            filename = fullfile(path, file);

            fid = fopen(filename, 'w');
            if fid == -1
                uialert(app.UIFigure, 'Ошибка открытия файла для записи.', 'Ошибка');
                return;
            end

            % Записываем бинарно поле BW.
            % Логический массив преобразуем в uint8 (0 или 1).
            bwData = uint8(maskStruct.BW);
            fwrite(fid, bwData, 'uint8');

            % Записываем маркер
            marker = 'META_START';
            fwrite(fid, marker, 'char');

            % Записываем метаинформацию: имя и размеры (маска – 2D)
            metaStr = sprintf('\n%s\n', maskStruct.listName);
            fwrite(fid, metaStr, 'char');
            dimsStr = sprintf('%d,', maskStruct.dimensions);
            dimsStr = dimsStr(1:end-1);
            dimsStr = sprintf('%s\n', dimsStr);
            fwrite(fid, dimsStr, 'char');

            fclose(fid);
            uialert(app.UIFigure, 'Mask сохранён.', 'Успех');
        end

        % Button pushed function: OpenFileMaskButton
        function OpenFileMaskButtonPushed(app, event)
            [file, path] = uigetfile({'*.bin','Binary Files'}, 'Открыть Mask');
            if isequal(file,0)
                return;
            end
            filename = fullfile(path, file);

            fid = fopen(filename, 'r');
            if fid == -1
                uialert(app.UIFigure, 'Ошибка открытия файла для чтения.', 'Ошибка');
                return;
            end

            fileBytes = fread(fid, inf, 'uint8=>uint8');
            fclose(fid);
            fileStr = char(fileBytes');
            marker = 'META_START';
            markerIdx = strfind(fileStr, marker);
            if isempty(markerIdx)
                uialert(app.UIFigure, 'Маркер META_START не найден в файле.', 'Ошибка');
                return;
            end

            % Бинарная часть – для маски, данные записаны как uint8
            binaryBytes = fileBytes(1:markerIdx-1);
            % Преобразуем в логический массив (каждый байт – 0 или 1)
            maskData = logical(binaryBytes);

            metaData = fileStr(markerIdx+length(marker):end);
            metaLines = strsplit(metaData, '\n');
            metaLines = metaLines(~cellfun('isempty', metaLines));
            if numel(metaLines) < 2
                uialert(app.UIFigure, 'Недостаточно мета данных.', 'Ошибка');
                return;
            end
            listName = metaLines{1};
            dimsStr = metaLines{2};
            dimsCell = strsplit(dimsStr, ',');
            dims = cellfun(@str2double, dimsCell);

            % Приводим данные маски к нужной размерности
            maskData = reshape(maskData, dims);

            newMaskStruct.BW = maskData;
            newMaskStruct.listName = listName;
            newMaskStruct.dimensions = dims;
            newMaskStruct.filePath = filename;

            % Добавляем в конец MaskList и обновляем списки
            app.MaskList{end+1} = newMaskStruct;
            app.updateLists();

            uialert(app.UIFigure, 'Mask открыт и добавлен в список.', 'Успех');
        end

        % Menu selected function: RawDeleteMenu
        function RawDeleteMenuSelected(app, event)
            index = app.FilesRawListBox.Value;
            if isempty(index)
                return;
            end
            answer = uiconfirm(app.UIFigure, sprintf('Удалить RawFile "%s"?', app.RawFiles{index}.listName), 'Подтверждение', 'Options', {'Да','Нет'}, 'DefaultOption', 2);
            if strcmp(answer, 'Да')
                app.RawFiles(index) = [];
                app.updateLists();
            end
        end

        % Menu selected function: RawRenameMenu
        function RawRenameMenuSelected(app, event)
            index = app.FilesRawListBox.Value;
            if isempty(index)
                return;
            end
            currentName = app.RawFiles{index}.listName;
            prompt = {'Введите новое имя:'};
            titleDlg = 'Edit Name';
            dims = [1 50];
            newName = inputdlg(prompt, titleDlg, dims, {currentName});
            if ~isempty(newName)
                app.RawFiles{index}.listName = newName{1};
                app.updateLists();
            end
        end

        % Menu selected function: ArrayDeleteMenu
        function ArrayDeleteMenuSelected(app, event)
            index = app.FilesListBox_Array.Value;
            if isempty(index)
                return;
            end
            answer = uiconfirm(app.UIFigure, sprintf('Удалить FFTArray "%s"?', app.FFTArrays{index}.listName), 'Подтверждение', 'Options', {'Да','Нет'}, 'DefaultOption', 2);
            if strcmp(answer, 'Да')
                app.FFTArrays(index) = [];
                app.updateLists();
            end
        end

        % Menu selected function: ArrayRenameMenu
        function ArrayRenameMenuSelected(app, event)
            index = app.FilesListBox_Array.Value;
            if isempty(index)
                return;
            end
            currentName = app.FFTArrays{index}.listName;
            prompt = {'Введите новое имя:'};
            titleDlg = 'Edit Name';
            dims = [1 50];
            newName = inputdlg(prompt, titleDlg, dims, {currentName});
            if ~isempty(newName)
                app.FFTArrays{index}.listName = newName{1};
                app.updateLists();
            end
        end

        % Menu selected function: MaskDeleteMenu
        function MaskDeleteMenuSelected(app, event)
            index = app.MaskListBox.Value;
            if isempty(index)
                return;
            end
            answer = uiconfirm(app.UIFigure, sprintf('Удалить Mask "%s"?', app.MaskList{index}.listName), 'Подтверждение', 'Options', {'Да','Нет'}, 'DefaultOption', 2);
            if strcmp(answer, 'Да')
                app.MaskList(index) = [];
                app.updateLists();
            end
        end

        % Menu selected function: MaskRenameMenu
        function MaskRenameMenuSelected(app, event)
            index = app.MaskListBox.Value;
            if isempty(index)
                return;
            end
            currentName = app.MaskList{index}.listName;
            prompt = {'Введите новое имя:'};
            titleDlg = 'Edit Name';
            dims = [1 50];
            newName = inputdlg(prompt, titleDlg, dims, {currentName});
            if ~isempty(newName)
                app.MaskList{index}.listName = newName{1};
                app.updateLists();
            end
        end

        % Button pushed function: SaveProjectButton
        function SaveProjectButtonPushed(app, event)
            % Запрашиваем у пользователя папку для сохранения проекта
            projectDir = uigetdir('', 'Выберите папку для сохранения проекта');
            if projectDir == 0
                return;
            end

            % Определяем пути для подпапок
            rawDir = fullfile(projectDir, 'Raw');
            arraysDir = fullfile(projectDir, 'Arrays');
            masksDir = fullfile(projectDir, 'Masks');

            % Создаем подпапки, если их нет
            if ~exist(rawDir, 'dir')
                mkdir(rawDir);
            end
            if ~exist(arraysDir, 'dir')
                mkdir(arraysDir);
            end
            if ~exist(masksDir, 'dir')
                mkdir(masksDir);
            end

            % Сохраняем RawFiles – копируем всю директорию, в которой находится raw
            for i = 1:length(app.RawFiles)
                rawStruct = app.RawFiles{i};
                if isfield(rawStruct, 'filePath') && ~isempty(rawStruct.filePath)
                    % Извлекаем директорию, имя файла и расширение
                    [srcDir, name, ext] = fileparts(rawStruct.filePath);
                    % Определяем имя целевой папки – например, берем имя исходной директории
                    [~, srcFolderName, ~] = fileparts(srcDir);
                    destDir = fullfile(rawDir, srcFolderName);
                    % Копируем всю исходную директорию в папку проекта
                    copyfile(srcDir, destDir);
                    % Обновляем путь в структуре: теперь файл находится в destDir с тем же именем
                    app.RawFiles{i}.filePath = fullfile(destDir, [name ext]);
                end
            end

            % Сохраняем FFTArrays – записываем бинарный файл, как в SaveFileArrayButton
            for i = 1:length(app.FFTArrays)
                fftStruct = app.FFTArrays{i};
                destFile = fullfile(arraysDir, [fftStruct.listName '.bin']);
                fid = fopen(destFile, 'w');
                if fid == -1, continue; end
                fwrite(fid, fftStruct.array, 'double');
                marker = 'META_START';
                fwrite(fid, marker, 'char');
                metaStr = sprintf('\n%s\n', fftStruct.listName);
                fwrite(fid, metaStr, 'char');
                dimsStr = sprintf('%d,', fftStruct.dimensions);
                dimsStr = dimsStr(1:end-1);  % удаляем завершающую запятую
                dimsStr = sprintf('%s\n', dimsStr);
                fwrite(fid, dimsStr, 'char');
                fclose(fid);
                app.FFTArrays{i}.filePath = destFile;
            end

            % Сохраняем MaskList – аналогично, сохраняем поле BW (логический массив)
            for i = 1:length(app.MaskList)
                maskStruct = app.MaskList{i};
                destFile = fullfile(masksDir, [maskStruct.listName '.bin']);
                fid = fopen(destFile, 'w');
                if fid == -1, continue; end
                % Преобразуем логический массив в uint8 (0 или 1)
                bwData = uint8(maskStruct.BW);
                fwrite(fid, bwData, 'uint8');
                marker = 'META_START';
                fwrite(fid, marker, 'char');
                metaStr = sprintf('\n%s\n', maskStruct.listName);
                fwrite(fid, metaStr, 'char');
                dimsStr = sprintf('%d,', maskStruct.dimensions);
                dimsStr = dimsStr(1:end-1);
                dimsStr = sprintf('%s\n', dimsStr);
                fwrite(fid, dimsStr, 'char');
                fclose(fid);
                app.MaskList{i}.filePath = destFile;
            end

            uialert(app.UIFigure, 'Проект сохранён.', 'Успех');

        end

        % Button pushed function: LoadProjectButton
        function LoadProjectButtonPushed(app, event)
            % Запрашиваем у пользователя папку проекта
            projectDir = uigetdir('', 'Выберите папку проекта');
            if projectDir == 0
                return;
            end

            rawDir = fullfile(projectDir, 'Raw');
            arraysDir = fullfile(projectDir, 'Arrays');
            masksDir = fullfile(projectDir, 'Masks');

            % Очищаем текущие списки
            app.RawFiles = {};
            app.FFTArrays = {};
            app.MaskList = {};

            % Загрузка RawFiles: для каждого файла в папке Raw
            if exist(rawDir, 'dir')
                rawFiles = dir(rawDir);
                rawFiles = rawFiles(~[rawFiles.isdir]);
                for i = 1:length(rawFiles)
                    fullPath = fullfile(rawDir, rawFiles(i).name);
                    [~, name, ext] = fileparts(fullPath);
                    newRaw.listName = [name ext];
                    newRaw.filePath = fullPath;
                    % Для Raw можно сохранить размеры, если требуется (например, [0 0 0] или получить информацию по файлу)
                    newRaw.dimensions = [0 0 0];
                    app.RawFiles{end+1} = newRaw;
                end
            end

            % Загрузка FFTArrays: для каждого файла в папке Arrays
            if exist(arraysDir, 'dir')
                arrayFiles = dir(arraysDir);
                arrayFiles = arrayFiles(~[arrayFiles.isdir]);
                for i = 1:length(arrayFiles)
                    fullPath = fullfile(arraysDir, arrayFiles(i).name);
                    fid = fopen(fullPath, 'r');
                    if fid == -1, continue; end
                    fileBytes = fread(fid, inf, 'uint8=>uint8');
                    fclose(fid);
                    fileStr = char(fileBytes');
                    marker = 'META_START';
                    markerIdx = strfind(fileStr, marker);
                    if isempty(markerIdx), continue; end
                    binaryBytes = fileBytes(1:markerIdx-1);
                    if mod(numel(binaryBytes), 8) ~= 0, continue; end
                    arrayData = typecast(binaryBytes, 'double');
                    metaData = fileStr(markerIdx+length(marker):end);
                    metaLines = strsplit(metaData, '\n');
                    metaLines = metaLines(~cellfun('isempty', metaLines));
                    if numel(metaLines) < 2, continue; end
                    listName = metaLines{1};
                    dimsStr = metaLines{2};
                    dimsCell = strsplit(dimsStr, ',');
                    dims = cellfun(@str2double, dimsCell);
                    arrayData = reshape(arrayData, dims);
                    newFFT.array = arrayData;
                    newFFT.listName = listName;
                    newFFT.dimensions = dims;
                    newFFT.filePath = fullPath;
                    app.FFTArrays{end+1} = newFFT;
                end
            end

            % Загрузка Masks: для каждого файла в папке Masks
            if exist(masksDir, 'dir')
                maskFiles = dir(masksDir);
                maskFiles = maskFiles(~[maskFiles.isdir]);
                for i = 1:length(maskFiles)
                    fullPath = fullfile(masksDir, maskFiles(i).name);
                    fid = fopen(fullPath, 'r');
                    if fid == -1, continue; end
                    fileBytes = fread(fid, inf, 'uint8=>uint8');
                    fclose(fid);
                    fileStr = char(fileBytes');
                    marker = 'META_START';
                    markerIdx = strfind(fileStr, marker);
                    if isempty(markerIdx), continue; end
                    binaryBytes = fileBytes(1:markerIdx-1);
                    maskData = logical(binaryBytes);
                    metaData = fileStr(markerIdx+length(marker):end);
                    metaLines = strsplit(metaData, '\n');
                    metaLines = metaLines(~cellfun('isempty', metaLines));
                    if numel(metaLines) < 2, continue; end
                    listName = metaLines{1};
                    dimsStr = metaLines{2};
                    dimsCell = strsplit(dimsStr, ',');
                    dims = cellfun(@str2double, dimsCell);
                    maskData = reshape(maskData, dims);
                    newMask.BW = maskData;
                    newMask.listName = listName;
                    newMask.dimensions = dims;
                    newMask.filePath = fullPath;
                    app.MaskList{end+1} = newMask;
                end
            end

            app.updateLists();
            uialert(app.UIFigure, 'Проект загружен.', 'Успех');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {539, 539};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {444, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.Panel.Title = 'Panel';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.RAWTab = uitab(app.TabGroup);
            app.RAWTab.Title = 'RAW';
            app.FilesListBoxLabel = uilabel(app.RAWTab);
            app.FilesListBoxLabel.Text = 'Files';
            app.FilesRawListBox = uilistbox(app.RAWTab);
            app.FilesRawListBox.Items = {};
            app.FilesRawListBox.ValueChangedFcn = createCallbackFcn(app, @RawListItemChanged, true);
            app.FilesRawListBox.Value = {};
            app.OpenFileRawButton = uibutton(app.RAWTab, 'push');
            app.OpenFileRawButton.ButtonPushedFcn = createCallbackFcn(app, @OpenRaw, true);
            app.OpenFileRawButton.Text = 'Open File';
            app.FFTButton = uibutton(app.RAWTab, 'push');
            app.FFTButton.ButtonPushedFcn = createCallbackFcn(app, @FFT, true);
            app.FFTButton.Text = 'FFT';
            app.outputnameEditFieldLabel = uilabel(app.RAWTab);
            app.outputnameEditFieldLabel.Text = 'output name';
            app.outputnameEditField = uieditfield(app.RAWTab, 'text');
            app.outputnameEditField.ValueChangedFcn = createCallbackFcn(app, @RawListItemChanged, true);
            app.outputnameEditField.Value = 'Array';
            app.XsizeEditFieldLabel = uilabel(app.RAWTab);
            app.XsizeEditFieldLabel.Enable = 'off';
            app.XsizeEditFieldLabel.Text = 'X size';
            app.XsizeEditField = uieditfield(app.RAWTab, 'numeric');
            app.XsizeEditField.Enable = 'off';
            app.YsizeEditFieldLabel = uilabel(app.RAWTab);
            app.YsizeEditFieldLabel.Enable = 'off';
            app.YsizeEditFieldLabel.Text = 'Y size';
            app.YsizeEditField = uieditfield(app.RAWTab, 'numeric');
            app.YsizeEditField.Enable = 'off';
            app.ZsizeEditFieldLabel = uilabel(app.RAWTab);
            app.ZsizeEditFieldLabel.Enable = 'off';
            app.ZsizeEditFieldLabel.Text = 'Z size';
            app.ZsizeEditField = uieditfield(app.RAWTab, 'numeric');
            app.ZsizeEditField.Enable = 'off';
            app.ZeroexpandButton = uibutton(app.RAWTab, 'push');
            app.ZeroexpandButton.Enable = 'off';
            app.ZeroexpandButton.Text = 'Zero expand';
            app.ArrayNamePreview = uilabel(app.RAWTab);
            app.ArrayNamePreview.Text = 'output name label';
            app.ARRAYSTab = uitab(app.TabGroup);
            app.ARRAYSTab.Title = 'ARRAYS';
            app.FilesListBox_2Label = uilabel(app.ARRAYSTab);
            app.FilesListBox_2Label.Text = 'Files';
            app.FilesListBox_Array = uilistbox(app.ARRAYSTab);
            app.FilesListBox_Array.Items = {};
            app.FilesListBox_Array.ValueChangedFcn = createCallbackFcn(app, @ArrayListValueChanged, true);
            app.FilesListBox_Array.Value = {};
            app.OpenFileArrayButton = uibutton(app.ARRAYSTab, 'push');
            app.OpenFileArrayButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileArrayButtonPushed, true);
            app.OpenFileArrayButton.Text = 'Open File';
            app.SubsamplingListBoxLabel = uilabel(app.ARRAYSTab);
            app.SubsamplingListBoxLabel.Text = 'Subsampling';
            app.SubsamplingListBox = uilistbox(app.ARRAYSTab);
            app.SubsamplingListBox.Items = {'X =', 'Y =', 'Z ='};
            app.SubsamplingListBox.Value = 'X =';
            app.x2Button = uibutton(app.ARRAYSTab, 'push');
            app.x2Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonX2Clicked, true);
            app.x2Button.Text = 'x2';
            app.Button_2 = uibutton(app.ARRAYSTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '/2';
            app.SavetolistButton = uibutton(app.ARRAYSTab, 'push');
            app.SavetolistButton.ButtonPushedFcn = createCallbackFcn(app, @SavetolistButtonPushed, true);
            app.SavetolistButton.Text = 'Save to list';
            app.newnameEditFieldLabel = uilabel(app.ARRAYSTab);
            app.newnameEditFieldLabel.Text = 'new name';
            app.newnameEditField = uieditfield(app.ARRAYSTab, 'text');
            app.SaveFileArrayButton = uibutton(app.ARRAYSTab, 'push');
            app.SaveFileArrayButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileArrayButtonPushed, true);
            app.SaveFileArrayButton.Text = 'Save File';
            app.MASKSTab = uitab(app.TabGroup);
            app.MASKSTab.Tooltip = {''};
            app.MASKSTab.Title = 'MASKS';
            app.MASKSTab.ButtonDownFcn = createCallbackFcn(app, @MASKSTabButtonDown, true);
            app.FilesListBox_3Label = uilabel(app.MASKSTab);
            app.FilesListBox_3Label.Text = 'Files';
            app.MaskListBox = uilistbox(app.MASKSTab);
            app.MaskListBox.Items = {};
            app.MaskListBox.Value = {};
            app.OpenFileMaskButton = uibutton(app.MASKSTab, 'push');
            app.OpenFileMaskButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileMaskButtonPushed, true);
            app.OpenFileMaskButton.Text = 'Open File';
            app.SaveMaskFileButton = uibutton(app.MASKSTab, 'push');
            app.SaveMaskFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMaskFileButtonPushed, true);
            app.SaveMaskFileButton.Text = 'Save File';
            app.CreateButton = uibutton(app.MASKSTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.ArrayDropDownLabel = uilabel(app.MASKSTab);
            app.ArrayDropDownLabel.Text = 'Array';
            app.ArrayDropDown = uidropdown(app.MASKSTab);
            app.ArrayDropDown.Items = {};
            app.ArrayDropDown.Value = {};
            app.LayerEditFieldLabel = uilabel(app.MASKSTab);
            app.LayerEditFieldLabel.Text = 'Layer';
            app.LayerEditField = uieditfield(app.MASKSTab, 'numeric');
            app.MaskNameEditFieldLabel = uilabel(app.MASKSTab);
            app.MaskNameEditFieldLabel.Text = 'Mask Name';
            app.MaskNameEditField = uieditfield(app.MASKSTab, 'text');
            app.WARNIGNSavemaskwithdafaultnameBWLabel = uilabel(app.MASKSTab);
            app.WARNIGNSavemaskwithdafaultnameBWLabel.Text = {'WARNIGN'; 'Save mask with dafault name BW'};
            app.SaveProjectButton = uibutton(app.LeftPanel, 'push');
            app.SaveProjectButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectButtonPushed, true);
            app.SaveProjectButton.Text = 'Save Project';
            app.LoadProjectButton = uibutton(app.LeftPanel, 'push');
            app.LoadProjectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadProjectButtonPushed, true);
            app.LoadProjectButton.Text = 'Load Project';
            app.CurrentprojectLabel = uilabel(app.LeftPanel);
            app.CurrentprojectLabel.Text = 'Current project';
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalyseLabel = uilabel(app.RightPanel);
            app.AnalyseLabel.Text = 'Analyse';
            app.ShowButton = uibutton(app.RightPanel, 'push');
            app.ShowButton.Text = 'Show';
            app.CompareButton = uibutton(app.RightPanel, 'push');
            app.CompareButton.Text = 'Compare';
            app.CalculateButton = uibutton(app.RightPanel, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Tree2 = uitree(app.RightPanel);
            app.QualityNode = uitreenode(app.Tree2);
            app.QualityNode.Text = 'Quality ';
            app.CNRNode = uitreenode(app.QualityNode);
            app.CNRNode.Text = 'CNR';
            app.SNRNode = uitreenode(app.QualityNode);
            app.SNRNode.Text = 'SNR';
            app.CommonNode = uitreenode(app.Tree2);
            app.CommonNode.Text = 'Common';
            app.MEANNode = uitreenode(app.CommonNode);
            app.MEANNode.Text = 'MEAN';
            app.SUBNode = uitreenode(app.CommonNode);
            app.SUBNode.Text = 'SUB';
            app.View3DButton = uibutton(app.RightPanel, 'push');
            app.View3DButton.Text = 'View 3D';
            app.ArrayLabel = uilabel(app.RightPanel);
            app.ArrayLabel.Text = 'Array:';
            app.ArraysLabel = uilabel(app.RightPanel);
            app.ArraysLabel.Text = 'Arrays:';
            app.MaskLabel_2 = uilabel(app.RightPanel);
            app.MaskLabel_2.Text = 'Mask:';
            app.ArrayLabel_2 = uilabel(app.RightPanel);
            app.ArrayLabel_2.Text = 'Array:';
            app.MaskLabel = uilabel(app.RightPanel);
            app.MaskLabel.Text = 'Mask:';
            app.RawContextMenu = uicontextmenu(app.UIFigure);
            app.RawDeleteMenu = uimenu(app.RawContextMenu);
            app.RawDeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @RawDeleteMenuSelected, true);
            app.RawDeleteMenu.Text = 'Delete';
            app.RawRenameMenu = uimenu(app.RawContextMenu);
            app.RawRenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RawRenameMenuSelected, true);
            app.RawRenameMenu.Text = 'Rename';
            % Assign app.RawContextMenu
            app.FilesRawListBox.ContextMenu = app.RawContextMenu;
            app.ArraysContextMenu = uicontextmenu(app.UIFigure);
            app.ArrayDeleteMenu = uimenu(app.ArraysContextMenu);
            app.ArrayDeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @ArrayDeleteMenuSelected, true);
            app.ArrayDeleteMenu.Text = 'Delete';
            app.ArrayRenameMenu = uimenu(app.ArraysContextMenu);
            app.ArrayRenameMenu.MenuSelectedFcn = createCallbackFcn(app, @ArrayRenameMenuSelected, true);
            app.ArrayRenameMenu.Text = 'Rename';
            % Assign app.ArraysContextMenu
            app.FilesListBox_Array.ContextMenu = app.ArraysContextMenu;
            app.MaskContextMenu = uicontextmenu(app.UIFigure);
            app.MaskDeleteMenu = uimenu(app.MaskContextMenu);
            app.MaskDeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @MaskDeleteMenuSelected, true);
            app.MaskDeleteMenu.Text = 'Delete';
            app.MaskRenameMenu = uimenu(app.MaskContextMenu);
            app.MaskRenameMenu.MenuSelectedFcn = createCallbackFcn(app, @MaskRenameMenuSelected, true);
            app.MaskRenameMenu.Text = 'Rename';
            % Assign app.MaskContextMenu
            app.MaskListBox.ContextMenu = app.MaskContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @FormStartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end